classdef HMI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        rollSliderLabel       matlab.ui.control.Label
        rollSlider            matlab.ui.control.Slider
        pitchSliderLabel      matlab.ui.control.Label
        pitchSlider           matlab.ui.control.Slider
        yawSliderLabel        matlab.ui.control.Label
        yawSlider             matlab.ui.control.Slider
        legtheta1SliderLabel  matlab.ui.control.Label
        legtheta1Slider       matlab.ui.control.Slider
        legtheta2SliderLabel  matlab.ui.control.Label
        legtheta2Slider       matlab.ui.control.Slider
        legtheta3SliderLabel  matlab.ui.control.Label
        legtheta3Slider       matlab.ui.control.Slider
        XSpinnerLabel         matlab.ui.control.Label
        XSpinner              matlab.ui.control.Spinner
        YSpinnerLabel         matlab.ui.control.Label
        YSpinner              matlab.ui.control.Spinner
        ZSpinnerLabel         matlab.ui.control.Label
        ZSpinner              matlab.ui.control.Spinner
        XSliderLabel          matlab.ui.control.Label
        XSlider               matlab.ui.control.Slider
        YSliderLabel          matlab.ui.control.Label
        YSlider               matlab.ui.control.Slider
        ZSliderLabel          matlab.ui.control.Label
        ZSlider               matlab.ui.control.Slider
        RollSliderLabel       matlab.ui.control.Label
        RollSlider            matlab.ui.control.Slider
        PitchSliderLabel      matlab.ui.control.Label
        PitchSlider           matlab.ui.control.Slider
        YawSliderLabel        matlab.ui.control.Label
        YawSlider             matlab.ui.control.Slider
    end


    properties (Access = public)
        hexapod = HEXAPOD();  % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            %             close all;
            clear;
        end

        % Value changed function: rollSlider
        function rollCallBack(app, event)
            value = app.rollSlider.Value;
            app.hexapod =app.hexapod.set_roll(value/180*pi);
            app.hexapod = app.hexapod.plotHexapod();
        end

        % Value changed function: pitchSlider
        function pitchCallBack(app, event)
            value = app.pitchSlider.Value;
            app.hexapod =app.hexapod.set_pitch(value/180*pi);
            app.hexapod = app.hexapod.plotHexapod();
        end

        % Value changed function: yawSlider
        function yawCallBack(app, event)
            value = app.yawSlider.Value;
            app.hexapod =app.hexapod.set_yaw(value/180*pi);
            app.hexapod = app.hexapod.plotHexapod();
        end

        % Value changed function: legtheta1Slider
        function legtheta1SliderValueChanged(app, event)
            value = app.legtheta1Slider.Value;
            app.hexapod.leg1.theta1 = value/180*pi;
            app.hexapod = app.hexapod.plotHexapod();
        end

        % Value changing function: legtheta1Slider
        function legtheta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.hexapod.leg1.theta1 = changingValue/180*pi;
            app.hexapod = app.hexapod.plotHexapod();
            plot3(app.hexapod.leg1.p4_W(1),app.hexapod.leg1.p4_W(2),app.hexapod.leg1.p4_W(3),'.');
        end

        % Close request function: UIFigure
        function close(app, event)
            delete(app);
            close all;
        end

        % Value changing function: legtheta2Slider
        function legtheta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.hexapod.leg1.theta2 = -changingValue/180*pi;
            app.hexapod = app.hexapod.plotHexapod();
            plot3(app.hexapod.leg1.p4_W(1),app.hexapod.leg1.p4_W(2),app.hexapod.leg1.p4_W(3),'.');
        end

        % Value changing function: legtheta3Slider
        function legtheta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.hexapod.leg1.theta3 = -changingValue/180*pi;
            app.hexapod = app.hexapod.plotHexapod();      
            plot3(app.hexapod.leg1.p4_W(1),app.hexapod.leg1.p4_W(2),app.hexapod.leg1.p4_W(3),'.');
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            changingValue = event.Value;
            p1 = app.hexapod.leg1.p4_W;
            p1(1) = -0.54+changingValue/10; 
            app.hexapod.leg1 = app.hexapod.leg1.OnrLegInverseKinematicsWoldAxis(p1);
            app.hexapod = app.hexapod.plotHexapod();
            %             disp(app.hexapod.leg1.p4_W);
            plot3(app.hexapod.leg1.p4_W(1),app.hexapod.leg1.p4_W(2),app.hexapod.leg1.p4_W(3),'.');
        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(app, event)
            changingValue = event.Value;
            p1 = app.hexapod.leg1.p4_W;
            p1(2) = 0.935307436087194+changingValue/10; 
            app.hexapod.leg1 = app.hexapod.leg1.OnrLegInverseKinematicsWoldAxis(p1);
            app.hexapod = app.hexapod.plotHexapod();
            %             disp(app.hexapod.leg1.p4_W);
            plot3(app.hexapod.leg1.p4_W(1),app.hexapod.leg1.p4_W(2),app.hexapod.leg1.p4_W(3),'.');
        end

        % Value changing function: ZSpinner
        function ZSpinnerValueChanging(app, event)
            changingValue = event.Value;
            p1 = app.hexapod.leg1.p4_W;
            p1(3) = -0.5+changingValue/10; 
            app.hexapod.leg1 = app.hexapod.leg1.OnrLegInverseKinematicsWoldAxis(p1);
            app.hexapod = app.hexapod.plotHexapod();
            %             disp(app.hexapod.leg1.p4_W);
            plot3(app.hexapod.leg1.p4_W(1),app.hexapod.leg1.p4_W(2),app.hexapod.leg1.p4_W(3),'.');
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            [temp,flag] = app.hexapod.changePos_fixLegPosition(changingValue,0,0,0,0,0);
            if (flag == 1)
                app.hexapod = temp;
                app.hexapod = app.hexapod.plotHexapod();
            end
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            [temp,flag] = app.hexapod.changePos_fixLegPosition(0,changingValue,0,0,0,0);
            if (flag == 1)
                app.hexapod = temp;
                app.hexapod = app.hexapod.plotHexapod();
            end
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            [temp,flag] = app.hexapod.changePos_fixLegPosition(0,0,changingValue,0,0,0);
            if (flag == 1)
                app.hexapod = temp;
                app.hexapod = app.hexapod.plotHexapod();
            end
        end

        % Value changing function: RollSlider
        function RollSliderValueChanging(app, event)
            changingValue = event.Value;
            [temp,flag] = app.hexapod.changePos_fixLegPosition(0,0,0,0,0,changingValue/180*3.14159);
            if (flag == 1)
                app.hexapod = temp;
                app.hexapod = app.hexapod.plotHexapod();
            end
        end

        % Value changing function: PitchSlider
        function PitchSliderValueChanging(app, event)
            changingValue = event.Value;
            [temp,flag] = app.hexapod.changePos_fixLegPosition(0,0,0,0,changingValue/180*3.14159,0);
            if (flag == 1)
                app.hexapod = temp;
                app.hexapod = app.hexapod.plotHexapod();
            end
        end

        % Value changing function: YawSlider
        function YawSliderValueChanging(app, event)
            changingValue = event.Value;
            [temp,flag] = app.hexapod.changePos_fixLegPosition(0,0,0,changingValue/180*3.14159,0,0);
            if (flag == 1)
                app.hexapod = temp;
                app.hexapod = app.hexapod.plotHexapod();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.rollSliderLabel = uilabel(app.UIFigure);
            app.rollSliderLabel.Text = 'roll';
            app.rollSlider = uislider(app.UIFigure);
            app.rollSlider.Limits = [-90 90];
            app.rollSlider.ValueChangedFcn = createCallbackFcn(app, @rollCallBack, true);
            app.pitchSliderLabel = uilabel(app.UIFigure);
            app.pitchSliderLabel.Text = 'pitch';
            app.pitchSlider = uislider(app.UIFigure);
            app.pitchSlider.Limits = [-90 90];
            app.pitchSlider.ValueChangedFcn = createCallbackFcn(app, @pitchCallBack, true);
            app.yawSliderLabel = uilabel(app.UIFigure);
            app.yawSliderLabel.Text = 'yaw';
            app.yawSlider = uislider(app.UIFigure);
            app.yawSlider.Limits = [-180 180];
            app.yawSlider.ValueChangedFcn = createCallbackFcn(app, @yawCallBack, true);
            app.legtheta1SliderLabel = uilabel(app.UIFigure);
            app.legtheta1SliderLabel.Text = 'legtheta1';
            app.legtheta1Slider = uislider(app.UIFigure);
            app.legtheta1Slider.Limits = [-45 45];
            app.legtheta1Slider.ValueChangedFcn = createCallbackFcn(app, @legtheta1SliderValueChanged, true);
            app.legtheta1Slider.ValueChangingFcn = createCallbackFcn(app, @legtheta1SliderValueChanging, true);
            app.legtheta2SliderLabel = uilabel(app.UIFigure);
            app.legtheta2SliderLabel.Text = 'legtheta2';
            app.legtheta2Slider = uislider(app.UIFigure);
            app.legtheta2Slider.Limits = [-90 90];
            app.legtheta2Slider.ValueChangingFcn = createCallbackFcn(app, @legtheta2SliderValueChanging, true);
            app.legtheta3SliderLabel = uilabel(app.UIFigure);
            app.legtheta3SliderLabel.Text = 'legtheta3';
            app.legtheta3Slider = uislider(app.UIFigure);
            app.legtheta3Slider.Limits = [0 150];
            app.legtheta3Slider.ValueChangingFcn = createCallbackFcn(app, @legtheta3SliderValueChanging, true);
            app.legtheta3Slider.Value = 90;
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.LowerLimitInclusive = 'off';
            app.XSpinner.UpperLimitInclusive = 'off';
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.XSpinner.ValueDisplayFormat = '%5.2f';
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @YSpinnerValueChanging, true);
            app.YSpinner.ValueDisplayFormat = '%5.2f';
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.ValueChangingFcn = createCallbackFcn(app, @ZSpinnerValueChanging, true);
            app.ZSpinner.ValueDisplayFormat = '%5.2f';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-1 1];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-1 1];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-1 1];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.RollSliderLabel = uilabel(app.UIFigure);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.UIFigure);
            app.RollSlider.Limits = [-90 90];
            app.RollSlider.ValueChangingFcn = createCallbackFcn(app, @RollSliderValueChanging, true);
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.Limits = [-90 90];
            app.PitchSlider.ValueChangingFcn = createCallbackFcn(app, @PitchSliderValueChanging, true);
            app.YawSliderLabel = uilabel(app.UIFigure);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.UIFigure);
            app.YawSlider.Limits = [-90 90];
            app.YawSlider.ValueChangingFcn = createCallbackFcn(app, @YawSliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = HMI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end